classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Vista3D          matlab.ui.control.CheckBox
        Paso             matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        SimularButton    matlab.ui.control.Button
        Ejes             matlab.ui.control.Slider
        EjesSliderLabel  matlab.ui.control.Label
        Grafico          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PasoValueChanged(app, event)
            value = app.Paso.Value;
            
        end

        % Value changed function: Ejes
        function EjesValueChanged(app, event)
            value = app.Ejes.Value;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.Grafico, "reset"); %función para limpiar Axis

            vpaso = app.Paso.Value; % valor del paso o separación de puntos
            vejes = app.Ejes.Value; %valor de los ejes
            x3d = app.Vista3D.Value; %valor de 3d

            %Generación del grid
            [x,y] = meshgrid(-vejes:vpaso:vejes);
            z = sin(x).*x;
            

            if (x3d == 0)
                plot(app.Grafico,x,y,"*r");
                grid(app.Grafico, "on")
                
            else
                plot3(app.Grafico,x,y,z,"*b");
                grid(app.Grafico, "on")

            end
            

        end

        % Value changed function: Vista3D
        function Vista3DValueChanged(app, event)
            value = app.Vista3D.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafico = uiaxes(app.UIFigure);
            title(app.Grafico, 'Title')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.EjesSliderLabel = uilabel(app.UIFigure);
            app.EjesSliderLabel.Text = 'Ejes';
            app.Ejes = uislider(app.UIFigure);
            app.Ejes.Limits = [3 8];
            app.Ejes.ValueChangedFcn = createCallbackFcn(app, @EjesValueChanged, true);
            app.Ejes.Value = 5;
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = {'Simular'; ''};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Paso = uieditfield(app.UIFigure, 'numeric');
            app.Paso.Limits = [0.2 0.8];
            app.Paso.Value = 0.4;
            app.Vista3D = uicheckbox(app.UIFigure);
            app.Vista3D.ValueChangedFcn = createCallbackFcn(app, @Vista3DValueChanged, true);
            app.Vista3D.Text = '3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end