classdef appEntregable1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultadoFem                matlab.ui.control.TextArea
        FEMINSTNTENEATextAreaLabel  matlab.ui.control.Label
        AlturaSlider                matlab.ui.control.Slider
        AlturaSliderLabel           matlab.ui.control.Label
        CorrrienteSlider            matlab.ui.control.Slider
        CorrrienteSliderLabel       matlab.ui.control.Label
        RadioSlider                 matlab.ui.control.Slider
        RadioSliderLabel            matlab.ui.control.Label
        GraficarButton              matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            % Campo magnetico
            r = app.RadioSlider.Value;% radio
            I = app.CorrrienteSlider.Value;% corriente
            h = app.AlturaSlider.Value;% altura
            g = 9.81; % gravedad
            M0 = 4*pi*10^-7; % permeabilidad del aire (miu)
            %masa = app.MasaSlider.Value; % toneladas
            
            tmax = sqrt((2*h)/g);
            paso = 0.01; %  paso 
            t=0:paso:tmax; % rango en el tiempo
            vel = @(t) t.*g;
            
            % Calculo Campo
            z = @(t) h + vel(0) .* t + (0.5.*-g.*t.^2);
            B = @(z) (M0*I*r.^2)./(2*(r.^2+z.^2).^(3/2)); %Campo
            
            %z = @(t) (t.*g).*t+(1/2).*g.*t.^2;
            
            z1 = z(t);
            b1 = B(z1);
            V = vel(t);
            A = 2.*pi.*r;
            
            fem = @(t) vel(t).*B(z(t)).*h;
            FEM = fem(t);
            pause(0.00000001);
            fInstantanea = sprintf('%.5f',FEM(end))

            %Etiqueta de fem
            app.ResultadoFem.Value = "";
            app.ResultadoFem.Value = fInstantanea +" volts";
            drawnow;
            
            % Grafica la Posicion respecto al tiempo 
            %plot(app.UIAxes,t,z1,'c')
            
            % Grafica fem respecto al tiempo
            plot(app.UIAxes,t,FEM,'b')
            
            % Grafica fem respecto a la posicion
            plot(app.UIAxes2,z1,FEM,'m')
            
            % Grafica la fem respecto a la Velocidad 
            plot(app.UIAxes3,V,FEM,'g')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FEM x Tiempo')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'FEM')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FEM x Posicion')
            xlabel(app.UIAxes2, 'Posición')
            ylabel(app.UIAxes2, 'FEM')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'FEM x Velocidad')
            xlabel(app.UIAxes3, 'Velocidad')
            ylabel(app.UIAxes3, 'FEM')
            zlabel(app.UIAxes3, 'Z')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.RadioSliderLabel = uilabel(app.UIFigure);
            app.RadioSliderLabel.Text = 'Radio';
            app.RadioSlider = uislider(app.UIFigure);
            app.RadioSlider.Limits = [1 10];
            app.RadioSlider.Value = 5;
            app.CorrrienteSliderLabel = uilabel(app.UIFigure);
            app.CorrrienteSliderLabel.Text = 'Corrriente';
            app.CorrrienteSlider = uislider(app.UIFigure);
            app.CorrrienteSlider.Limits = [10 100];
            app.CorrrienteSlider.Value = 10;
            app.AlturaSliderLabel = uilabel(app.UIFigure);
            app.AlturaSliderLabel.Text = 'Altura';
            app.AlturaSlider = uislider(app.UIFigure);
            app.AlturaSlider.Limits = [40 140];
            app.AlturaSlider.Value = 40;
            app.FEMINSTNTENEATextAreaLabel = uilabel(app.UIFigure);
            app.FEMINSTNTENEATextAreaLabel.Text = 'FEM INSTÁNTENEA : ';
            app.ResultadoFem = uitextarea(app.UIFigure);
            app.ResultadoFem.Value = {'0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appEntregable1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end