classdef appEntregable3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MasaSlider              matlab.ui.control.Slider
        MasaSliderLabel         matlab.ui.control.Label
        MomentoSlider           matlab.ui.control.Slider
        MomentoSliderLabel      matlab.ui.control.Label
        RadioSlider             matlab.ui.control.Slider
        RadioSliderLabel        matlab.ui.control.Label
        ResistenciaSlider       matlab.ui.control.Slider
        ResistenciaSliderLabel  matlab.ui.control.Label
        VueltasSlider           matlab.ui.control.Slider
        VueltasSliderLabel      matlab.ui.control.Label
        GraficarButton          matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %Propiedades del medio
            mu = 4*pi*1e-7;                      % Permeabilidad del medio
            g = 9.81;                       % Constante de gravedad(m/s^2)
            
            %Valores de la espira de la espira
            N= app.VueltasSlider.Value;                         % Cantidad de espiras (30)
            Ohm = app.ResistenciaSlider.Value;                     % Resistencia de la espira (0.05)
            R= app.RadioSlider.Value;                        % Radio de la espira (m) (0.01)
            
            % Propiedades del dipolo
            M = app.MomentoSlider.Value;                             % Momento magnético dipolar (Am^2) 30
            m = app.MasaSlider.Value;                           % Masa del dipolo magnético (kg) 0.003
            
            % Constantes
            km = 9*(mu^2)*(M^2)*(R^4)*(N^2)/(4*m*Ohm);
            km2 = 3*mu*M*N*(R^2)/(2*Ohm);

            %Condiciones iniciales
            v0 = 0;% 0
            z0 = .05;% 0.05

            % Tiempo de simulación
            tt = 1.0; %1.2


            A = @(z, v) - g - (km * (z.^2)./((R.^2 + z.^2).^5)) .* v;

            dydt = @(t, y) [y(2); A(y(1), y(2))];
            
            [t,y] = ode45(dydt,[0 tt],[z0; v0]); %fem
            
            z = y(:,1);
            v = y(:,2);
            a = A(z, v);
            f = m*a;
            I = km*z.*v./((R*R+z.*z).^(2.5));
            
            % Grafica fem respecto al tiempo
            plot(app.UIAxes,t,z,'b')
            
            % Grafica fem respecto a la posicion
            plot(app.UIAxes2,t,v,'m')
            
            % Grafica la fem respecto a la Velocidad 
            plot(app.UIAxes3,t, a,'g')

            % Grafica
            plot(app.UIAxes4,t,I,'c')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tiempo x Posicion')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Posicion')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Tiempo x Velocidad')
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Velocidad')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Tiempo x Aceleracion')
            xlabel(app.UIAxes3, 'Tiempo')
            ylabel(app.UIAxes3, 'Aceleracion')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Tiempo x Corriente')
            xlabel(app.UIAxes4, 'Tiempo')
            ylabel(app.UIAxes4, 'Corriente')
            zlabel(app.UIAxes4, 'Z')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.VueltasSliderLabel = uilabel(app.UIFigure);
            app.VueltasSliderLabel.Text = 'Vueltas';
            app.VueltasSlider = uislider(app.UIFigure);
            app.VueltasSlider.Value = 1;
            app.ResistenciaSliderLabel = uilabel(app.UIFigure);
            app.ResistenciaSliderLabel.Text = 'Resistencia';
            app.ResistenciaSlider = uislider(app.UIFigure);
            app.ResistenciaSlider.Limits = [0 1];
            app.ResistenciaSlider.Value = 0.1;
            app.RadioSliderLabel = uilabel(app.UIFigure);
            app.RadioSliderLabel.Text = 'Radio';
            app.RadioSlider = uislider(app.UIFigure);
            app.RadioSlider.Limits = [0 0.1];
            app.MomentoSliderLabel = uilabel(app.UIFigure);
            app.MomentoSliderLabel.Text = 'Momento';
            app.MomentoSlider = uislider(app.UIFigure);
            app.MomentoSlider.Limits = [0 20];
            app.MasaSliderLabel = uilabel(app.UIFigure);
            app.MasaSliderLabel.Text = 'Masa';
            app.MasaSlider = uislider(app.UIFigure);
            app.MasaSlider.Limits = [0 0.01];
            app.MasaSlider.Value = 0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appEntregable3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end