classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Titulo            matlab.ui.control.Label
        Graficar          matlab.ui.control.Button
        Ejes              matlab.ui.control.Slider
        EjesSliderLabel   matlab.ui.control.Label
        Paso              matlab.ui.control.Spinner
        PasoSpinnerLabel  matlab.ui.control.Label
        TipoGraph         matlab.ui.container.ButtonGroup
        Plot3             matlab.ui.control.RadioButton
        Surf              matlab.ui.control.RadioButton
        Mesh              matlab.ui.control.RadioButton
        Grafico           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Ejes
        function EjesValueChanged(app, event)
            value = app.Ejes.Value;
            
        end

        % Value changed function: Paso
        function PasoValueChanged(app, event)
            value = app.Paso.Value;
            
        end

        % Selection changed function: TipoGraph
        function TipoGraphSelectionChanged(app, event)
            selectedButton = app.TipoGraph.SelectedObject;
            op1 = app.Mesh.Value;
            op2 = app.Surf.Value;
            op3 = app.Plot3.Value;
            
        end

        % Button pushed function: Graficar
        function GraficarButtonPushed(app, event)
            cla(app.Grafico,"reset");
            vpaso = app.Paso.Value;
            vejes = app.Ejes.Value;
            opmesh = app.Mesh.Value;
            opsurf = app.Surf.Value;
            opplot3 = app.Plot3.Value;
 

            [x,y,] = meshgrid(-vejes:vpaso:vejes);
            r = sqrt (x.^2+y.^2)+eps;
            z = sin(r)./r;

            if opmesh == 1
                mesh(app.Grafico,x,y,z);
            elseif opsurf ==1
                surf(app.Grafico,x,y,z);
            elseif opplot3 == 1
                plot3(app.Grafico,x,y,z);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafico = uiaxes(app.UIFigure);
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.TipoGraph = uibuttongroup(app.UIFigure);
            app.TipoGraph.SelectionChangedFcn = createCallbackFcn(app, @TipoGraphSelectionChanged, true);
            app.TipoGraph.Title = 'Tipo de Gráfica';
            app.Mesh = uiradiobutton(app.TipoGraph);
            app.Mesh.Text = 'Mesh';
            app.Mesh.Value = true;
            app.Surf = uiradiobutton(app.TipoGraph);
            app.Surf.Text = 'Surf';
            app.Plot3 = uiradiobutton(app.TipoGraph);
            app.Plot3.Text = 'Plot3';
            app.PasoSpinnerLabel = uilabel(app.UIFigure);
            app.PasoSpinnerLabel.Text = 'Paso';
            app.Paso = uispinner(app.UIFigure);
            app.Paso.Step = 0.1;
            app.Paso.Limits = [0.1 1];
            app.Paso.ValueChangedFcn = createCallbackFcn(app, @PasoValueChanged, true);
            app.Paso.Value = 0.5;
            app.EjesSliderLabel = uilabel(app.UIFigure);
            app.EjesSliderLabel.Text = 'Ejes';
            app.Ejes = uislider(app.UIFigure);
            app.Ejes.Limits = [5 10];
            app.Ejes.ValueChangedFcn = createCallbackFcn(app, @EjesValueChanged, true);
            app.Ejes.Value = 7.5;
            app.Graficar = uibutton(app.UIFigure, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.Graficar.Text = 'Graficar';
            app.Titulo = uilabel(app.UIFigure);
            app.Titulo.Text = 'EJEMPLOS DE GRAFICACIÓN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end